classdef gui_classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DetectionApplicationUIFigure  matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        LoadImageMenu                   matlab.ui.container.Menu
        DetectionMenu                   matlab.ui.container.Menu
        GuideMenu_2                     matlab.ui.container.Menu
        MedicineadviceMenu              matlab.ui.container.Menu
        EnhancmentTechniquesMenu        matlab.ui.container.Menu
        OriginalMenu                    matlab.ui.container.Menu
        HisteqMenu                      matlab.ui.container.Menu
        HistogramofHisteqImageMenu      matlab.ui.container.Menu
        CLAHEMenu                       matlab.ui.container.Menu
        HistogramofCLAHEImageMenu       matlab.ui.container.Menu
        ComplementMenu                  matlab.ui.container.Menu
        HistogramofComplementImageMenu  matlab.ui.container.Menu
        GammaMenu                       matlab.ui.container.Menu
        HistogramofGammaImageMenu       matlab.ui.container.Menu
        BCETMenu                        matlab.ui.container.Menu
        HistogramofBCETImageMenu        matlab.ui.container.Menu
        AllEnhancmentMenu               matlab.ui.container.Menu
        CAMMenu                         matlab.ui.container.Menu
        InfromationMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        PAECMonogram                    matlab.ui.control.Image
        COVID19DetectionAnalysisusingCTLungsImagesLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        LoadImageButton                 matlab.ui.control.Button
        OriginalImage                   matlab.ui.control.Image
        HisteqImageButton               matlab.ui.control.Button
        CLAHEButton                     matlab.ui.control.Button
        GammaButton                     matlab.ui.control.Button
        ComplementButton                matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CAMButton_3                     matlab.ui.control.Button
        DetectionButton                 matlab.ui.control.Button
        AccuracyButton_2                matlab.ui.control.Button
        ResizeImageButton               matlab.ui.control.Button
        EnhancmentTechniquesLabel       matlab.ui.control.Label
        BCETButton                      matlab.ui.control.Button
        OriginalImageButton             matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DetectionLabel                  matlab.ui.control.Label
        DetectionLabelTitle             matlab.ui.control.Label
        PAICMonogram                    matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        PAECMonogram_2                  matlab.ui.control.Image
        mylamp                          matlab.ui.control.Lamp
    end

    
    properties (Access = public)
         original_image          % to store original image
         resized_image           % image after dwt preprocessing
         classification_model    %   
         
         %Variables for different filters
         output_image
         chache_image
         gamma_image
         complement_image
         bcet_image
         
         %Path
         path = 'D:\Study_University_Borys_Bilkevych_4_course_1_semestr\Diplom_work\October 11 2021\Staging-COVID-19-Detection-System-using-Chest-X-Ray-Images-master'
         
         %Detection result
         A
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = uifigure; d = uiprogressdlg(fig,'Title','Loading Classification Model','Indeterminate','on'); drawnow;
            load('../proposed-CB-STM-RENet-model/Proposed_CB_STM_RENet.mat','Proposed_CB_STM_RENet');
            app.classification_model = Proposed_CB_STM_RENet;
            delete(d); delete(fig);
        end

        % Callback function: LoadImageButton, LoadImageMenu
        function LoadImageButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png'},'Select Image File',strcat(app.path,'\Covid-X-ray-images'));
            if class(filename) == "double"
                return;
            end
            
            app.original_image = imread([filepath, filename]);
            
            channels = size(size(app.original_image));
            if channels(2) == 2
                app.original_image = cat(3, app.original_image, app.original_image, app.original_image);
            end
            
            
            app.OriginalImage.ImageSource = app.original_image;
            
            app.DetectionLabelTitle.Text = "";
            
            %Enable all the buttons on the main page
            app.DetectionButton.Enable = 1;
            app.OriginalImage.Enable = 1;
            app.HisteqImageButton.Enable = 1;
            app.CLAHEButton.Enable = 1;
            app.GammaButton.Enable = 1;
            app.ComplementButton.Enable = 1;
            app.Button.Enable = 1;
            app.CAMButton_3.Enable = 1;
            app.DetectionButton.Enable = 1;
            app.AccuracyButton_2.Enable = 1;
            app.ResizeImageButton.Enable = 1;
            app.EnhancmentTechniquesLabel.Enable = 1;
            app.BCETButton.Enable = 1;
            app.OriginalImageButton.Enable = 1;
            
            %Enable all the button on the menu
            app.DetectionMenu.Enable = 1;
            app.HisteqMenu.Enable = 1;
            app.BCETMenu.Enable = 1;
            app.OriginalMenu.Enable = 1;
            app.AllEnhancmentMenu.Enable = 1;
            app.GammaMenu.Enable = 1;
            app.CLAHEMenu.Enable = 1;
            app.ComplementMenu.Enable = 1;

            app.DetectionLabel.Text = "Click Detection Button";
            app.DetectionLabel.FontColor = [0 0 0];
        end

        % Button pushed function: ResizeImageButton
        function ResizeImageButtonPushed(app, event)
            app.resized_image = imresize(app.original_image, [224, 224]);
            
            channels = size(size(app.resized_image));
            if channels(2) == 2
                app.resized_image = cat(3, app.resized_image, app.resized_image, app.resized_image);
            end
            
            app.OriginalImage.ImageSource = app.resized_image;

        end

        % Button pushed function: OriginalImageButton
        function OriginalImageButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);        
            Original_Image = app.resized_image;
            
            %ImShow the Original Image
            figure, imshow(Original_Image);
            title('Original Image'); 
        end

        % Callback function: HisteqImageButton, HisteqMenu
        function HisteqImageButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);
            histeqimage=histeq(app.resized_image);            
            app.OriginalImage.ImageSource = histeqimage;
        
            Original_Image = app.resized_image;

            numofpixels = size(Original_Image,1) * size(Original_Image,2);
            
            app.output_image = uint8(zeros(size(Original_Image,1), size(Original_Image,2)));
            frequency = zeros(256,1); 
            prob_f = zeros(256,1);
            prob_c = zeros(256,1);
            accumulator = zeros(256,1);
            output = zeros(256,1);
            
            %frequency counts the occurrence of each pixel value.
            %The probability of each occurrence is calculated by prob_f.
            for i=1:size(Original_Image,1)
                for j=1:size(Original_Image,2)
                    value = Original_Image(i,j);
                    frequency(value+1) = frequency(value+1)+1;
                    prob_f(value+1) = frequency(value+1)/numofpixels;
                end
            end
            
            sum = 0;
            no_bins = 255;
            
            %The accumulatorulative distribution probability is calculated.
            for i=1:size(prob_f)
               sum = sum+frequency(i);
               accumulator(i) = sum;
               prob_c(i) = accumulator(i) / numofpixels;
               output(i) = round(prob_c(i) * no_bins);
            end
            
            for i=1:size(Original_Image,1)
                for j=1:size(Original_Image,2)
                        app.output_image(i,j) = output(Original_Image(i,j) + 1);
                end
            end
                        
            %Imshow the Image after Histogram equalization 
            imshowpair(app.resized_image,app.output_image,'montage');
            title('Left: Original Image and Right: Histeq Enhanced Image');

        end

        % Menu selected function: HistogramofHisteqImageMenu
        function HistogramofHisteqImageMenuSelected(app, event)
            HisteqImageButtonPushed(app, event)
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.output_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Callback function: CLAHEButton, CLAHEMenu
        function CLAHEButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);
            
            clache_image=app.resized_image;            
                        
            app.chache_image = adapthisteq(rgb2gray(clache_image),'clipLimit',0.02,'Distribution','rayleigh');
            app.OriginalImage.ImageSource = clache_image;

            %ImShow the CLACHE Image
            imshowpair(app.resized_image,app.chache_image,'montage');
            title('Left: Original Image and Right: CLACHE Enhanced Image');

        end

        % Menu selected function: HistogramofCLAHEImageMenu
        function HistogramofCLAHEImageMenuSelected(app, event)
            ResizeImageButtonPushed(app, event);
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.chache_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Callback function: GammaButton, GammaMenu
        function GammaButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);
            app.gamma_image=imadjust(app.resized_image,[0 0.2],[0.5 1]);
            app.OriginalImage.ImageSource = app.gamma_image;

            %ImShow the Gamma Image
            imshowpair(app.resized_image,app.gamma_image,'montage');
            title('Left: Original Image Right: Gamma Enhanced Image');
        end

        % Menu selected function: HistogramofGammaImageMenu
        function HistogramofGammaImageMenuSelected(app, event)
            ResizeImageButtonPushed(app, event);
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.gamma_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Callback function: ComplementButton, ComplementMenu
        function ComplementButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);
            app.complement_image=imcomplement(app.resized_image);            
            app.OriginalImage.ImageSource = app.complement_image;
            
            %ImShow the CLACHE Image
            imshowpair(app.resized_image,app.complement_image,'montage');
            title('Left: Original Image and Right: Complement Enhanced Image');
        end

        % Menu selected function: HistogramofComplementImageMenu
        function HistogramofComplementImageMenuSelected(app, event)
            ResizeImageButtonPushed(app, event);
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.complement_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Callback function: BCETButton, BCETMenu
        function BCETButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);
            I = app.resized_image;
            
            x    = double(I); % INPUT IMAGE
            Lmin = min(x(:)); % MINIMUM OF INPUT IMAGE
            Lmax = max(x(:)); % MAXIMUM OF INPUT IMAGE
            Lmean = mean(x(:)); %MEAN OF INPUT IMAGE
            LMssum = mean(x(:).^2); %MEAN SQUARE SUM OF INPUT IMAGE
            
            Gmin = 0; %MINIMUM OF OUTPUT IMAGE
            Gmax = 255; %MAXIMUM OF OUTPUT IMAGE
            Gmean = 110; %MEAN OF OUTPUT IMAGE
            
            bnum = Lmax.^2*(Gmean-Gmin) - LMssum*(Gmax-Gmin) + Lmin.^2*(Gmax-Gmean);
            bden = 2*(Lmax*(Gmean-Gmin)-Lmean*(Gmax-Gmin)+Lmin*(Gmax-Gmean));
            
            b = bnum/bden;
            a = (Gmax-Gmin)/((Lmax-Lmin)*(Lmax+Lmin-2*b));
            c = Gmin - a*(Lmin-b).^2;
            app.bcet_image = a*(x-b).^2+c; %PARABOLIC FUNCTION
            app.bcet_image = uint8(app.bcet_image);
            
            app.OriginalImage.ImageSource = app.bcet_image;

            %ImShow the BCET Image
            imshowpair(app.resized_image,app.bcet_image,'montage');
            title('Original Image (left) and BCET Enhanced Image (right)');
        end

        % Menu selected function: HistogramofBCETImageMenu
        function HistogramofBCETImageMenuSelected(app, event)
            ResizeImageButtonPushed(app, event);
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.bcet_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Menu selected function: AllEnhancmentMenu
        function AllEnhancmentMenuSelected(app, event)
            
            subplot(2,3,1); imshow(app.resized_image);
            title(sprintf('Original image'));
            
            subplot(2,3,2); imshow(app.output_image);
            title(sprintf('Original image'));
            
            subplot(2,3,3); imshow(app.chache_image);
            title(sprintf('Original image'));
            
            subplot(2,3,4); imshow(app.gamma_image);
            title(sprintf('Original image'));
            
            subplot(2,3,5); imshow(app.complement_image);
            title(sprintf('Original image'));
            
            subplot(2,3,6); imshow(app.bcet_image);
            title(sprintf('Original image'));
        end

        % Callback function: CAMButton_3, CAMMenu
        function CAMButton_3Pushed(app, event)
            ResizeImageButtonPushed(app, event);
            imSize = size(im);
            CAM = imresize(CAM,imSize(1:2));
            
            minimum = min(CAM(:));
            maximum = max(CAM(:));
            N = (CAM-minimum)/(maximum-minimum);
            CAM=N;
            
            CAM(CAM<0.2) = 0;
            cmap = jet(255).*linspace(0,1,255)';
            CAM = ind2rgb(uint8(CAM*255),cmap)*255;
            
            combinedImage = double(rgb2gray(im))/2 + CAM;
            
            minimum = min(combinedImage(:));
            maximum = max(combinedImage(:));
            NN = (combinedImage-minimum)/(maximum-minimum);
            
            combinedImage = NN*255;
            
            imshow(uint8(combinedImage));
        end

        % Callback function: DetectionButton, DetectionMenu
        function DetectionButtonPushed(app, event)
            ResizeImageButtonPushed(app, event);
            app.MedicineadviceMenu.Enable = 1;
            
            fig = uifigure; d = uiprogressdlg(fig,'Title','Running Classification Model','Indeterminate','on'); drawnow;
            [predicted_labels,posterior] = classify(app.classification_model,app.resized_image);
            delete(d); delete(fig);
            
            fprintf("posterior: %f\n", posterior);
            fprintf("predicted_labels: %f\n", predicted_labels);
            app.A = categorical(predicted_labels);
            
            if(app.A == "COVID-19")
                app.DetectionLabelTitle.Text = "Dangerous";
                app.DetectionLabel.Text = """You are COVID-19 Infected""";
                app.DetectionLabelTitle.FontColor = [1 0 0];
                app.DetectionLabel.FontColor = [1 0 0];
                app.mylamp.Color = [1.00,0.00,0.00];
            else
                %app.DetectionLabelTitle.Text = "Congratulations";
                app.DetectionLabel.Text = """You are Non-COVID-19 Infected""";
                app.DetectionLabelTitle.FontColor = [0 1 0];
                app.DetectionLabel.FontColor = [0 1 0];
                app.mylamp.Color = [0.00,1.00,0.00];
            end
        end

        % Menu selected function: GuideMenu_2
        function GuideMenu_2Selected(app, event)
            uiwait(msgbox({'1. Before starting the program, please check if you have a model file'; '2. After starting the programm Load the X-ray image'; ...
                '3. ';'4. '; ...
                '5. ';'6. '},'Guide for Detection tool',"help",'modal'));
        end

        % Menu selected function: InfromationMenu
        function InfromationMenuSelected(app, event)
            uiwait(msgbox({'1. Before starting the program, please check if you have a model file'; '2. After starting the programm Load the X-ray image'; ...
                '3. ';'4. '; ...
                '5. ';'6. '},'Guide for Detection tool',"help",'modal'));
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            uiwait(msgbox({'1. Before starting the program, please check if you have a model file'; '2. After starting the programm Load the X-ray image'; ...
                '3. ';'4. '; ...
                '5. ';'6. '},'Guide for Detection tool',"help",'modal'));
        end

        % Menu selected function: MedicineadviceMenu
        function MedicineadviceMenuSelected(app, event)
            if(app.A == "COVID-19")
                uiwait(msgbox({'1. Stay at home and call to your local doctor for help'; '2. After consultation. Follow all instructions'; ...
                    '3. Spend more time outdoors, always ventilate the house. Also drink and eat more vitamins';'4. If it gets worse, be hospitalized immediately'; ...
                    '5. Make a vaccination'},'Medicine advice for ill people',"error",'modal'));
            else
                uiwait(msgbox({'1. Be carefuk outdoor'; '2. Eat and drink more vitamins'; ...
                    '3. Try to avoid the conversation with ill people';'4. Make a vaccination'},'Medicine advice for helthy people',"help",'modal'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DetectionApplicationUIFigure = uifigure('Visible', 'off');
            app.Covid19DetectionApplicationUIFigure.Name = 'Covid-19 Detection Application';
            app.Covid19DetectionApplicationUIFigure.Icon = 'good.jpg';
            app.Menu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadImageMenu = uimenu(app.Menu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.DetectionMenu = uimenu(app.Menu);
            app.DetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionMenu.Enable = 'off';
            app.DetectionMenu.Text = 'Detection';
            app.GuideMenu_2 = uimenu(app.Menu);
            app.GuideMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GuideMenu_2Selected, true);
            app.GuideMenu_2.Text = 'Guide';
            app.MedicineadviceMenu = uimenu(app.Menu);
            app.MedicineadviceMenu.MenuSelectedFcn = createCallbackFcn(app, @MedicineadviceMenuSelected, true);
            app.MedicineadviceMenu.Enable = 'off';
            app.MedicineadviceMenu.Text = 'Medicine advice';
            app.EnhancmentTechniquesMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.EnhancmentTechniquesMenu.Text = 'Enhancment Techniques';
            app.OriginalMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.OriginalMenu.Enable = 'off';
            app.OriginalMenu.Text = 'Original';
            app.HisteqMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.HisteqMenu.MenuSelectedFcn = createCallbackFcn(app, @HisteqImageButtonPushed, true);
            app.HisteqMenu.Enable = 'off';
            app.HisteqMenu.Text = 'Histeq';
            app.HistogramofHisteqImageMenu = uimenu(app.HisteqMenu);
            app.HistogramofHisteqImageMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofHisteqImageMenuSelected, true);
            app.HistogramofHisteqImageMenu.Text = 'Histogram of Histeq Image';
            app.CLAHEMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.CLAHEMenu.MenuSelectedFcn = createCallbackFcn(app, @CLAHEButtonPushed, true);
            app.CLAHEMenu.Enable = 'off';
            app.CLAHEMenu.Text = 'CLAHE';
            app.HistogramofCLAHEImageMenu = uimenu(app.CLAHEMenu);
            app.HistogramofCLAHEImageMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofCLAHEImageMenuSelected, true);
            app.HistogramofCLAHEImageMenu.Text = 'Histogram of CLAHE Image';
            app.ComplementMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.ComplementMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementMenu.Enable = 'off';
            app.ComplementMenu.Text = 'Complement';
            app.HistogramofComplementImageMenu = uimenu(app.ComplementMenu);
            app.HistogramofComplementImageMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofComplementImageMenuSelected, true);
            app.HistogramofComplementImageMenu.Text = 'Histogram of Complement Image';
            app.GammaMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.GammaMenu.MenuSelectedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaMenu.Enable = 'off';
            app.GammaMenu.Text = 'Gamma';
            app.HistogramofGammaImageMenu = uimenu(app.GammaMenu);
            app.HistogramofGammaImageMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofGammaImageMenuSelected, true);
            app.HistogramofGammaImageMenu.Text = 'Histogram of Gamma Image';
            app.BCETMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.BCETMenu.MenuSelectedFcn = createCallbackFcn(app, @BCETButtonPushed, true);
            app.BCETMenu.Enable = 'off';
            app.BCETMenu.Text = 'BCET';
            app.HistogramofBCETImageMenu = uimenu(app.BCETMenu);
            app.HistogramofBCETImageMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofBCETImageMenuSelected, true);
            app.HistogramofBCETImageMenu.Text = 'Histogram of BCET Image';
            app.AllEnhancmentMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.AllEnhancmentMenu.MenuSelectedFcn = createCallbackFcn(app, @AllEnhancmentMenuSelected, true);
            app.AllEnhancmentMenu.Enable = 'off';
            app.AllEnhancmentMenu.Text = 'All Enhancment';
            app.CAMMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.CAMMenu.MenuSelectedFcn = createCallbackFcn(app, @CAMButton_3Pushed, true);
            app.CAMMenu.Text = 'CAM';
            app.InfromationMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.InfromationMenu.MenuSelectedFcn = createCallbackFcn(app, @InfromationMenuSelected, true);
            app.InfromationMenu.Text = 'Infromation';
            app.HelpMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.Covid19DetectionApplicationUIFigure);
            app.PAECMonogram = uiimage(app.GridLayout);
            app.PAECMonogram.ImageSource = 'frecs_en.png';
            app.COVID19DetectionAnalysisusingCTLungsImagesLabel = uilabel(app.GridLayout);
            app.COVID19DetectionAnalysisusingCTLungsImagesLabel.Text = {'COVID-19 Detection'; 'Analysis using CT Lungs Images'};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.OriginalImage = uiimage(app.GridLayout2);
            app.OriginalImage.Enable = 'off';
            app.OriginalImage.ImageSource = 'good.jpg';
            app.HisteqImageButton = uibutton(app.GridLayout2, 'push');
            app.HisteqImageButton.ButtonPushedFcn = createCallbackFcn(app, @HisteqImageButtonPushed, true);
            app.HisteqImageButton.Enable = 'off';
            app.HisteqImageButton.Text = 'Histeq Image';
            app.CLAHEButton = uibutton(app.GridLayout2, 'push');
            app.CLAHEButton.ButtonPushedFcn = createCallbackFcn(app, @CLAHEButtonPushed, true);
            app.CLAHEButton.Enable = 'off';
            app.CLAHEButton.Text = 'CLAHE';
            app.GammaButton = uibutton(app.GridLayout2, 'push');
            app.GammaButton.ButtonPushedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaButton.Enable = 'off';
            app.GammaButton.Text = 'Gamma';
            app.ComplementButton = uibutton(app.GridLayout2, 'push');
            app.ComplementButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementButton.Enable = 'off';
            app.ComplementButton.Text = 'Complement';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '???';
            app.CAMButton_3 = uibutton(app.GridLayout2, 'push');
            app.CAMButton_3.ButtonPushedFcn = createCallbackFcn(app, @CAMButton_3Pushed, true);
            app.CAMButton_3.Enable = 'off';
            app.CAMButton_3.Text = 'CAM';
            app.DetectionButton = uibutton(app.GridLayout2, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Enable = 'off';
            app.DetectionButton.Text = 'Detection';
            app.AccuracyButton_2 = uibutton(app.GridLayout2, 'push');
            app.AccuracyButton_2.Enable = 'off';
            app.AccuracyButton_2.Text = 'Accuracy';
            app.ResizeImageButton = uibutton(app.GridLayout2, 'push');
            app.ResizeImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeImageButtonPushed, true);
            app.ResizeImageButton.Enable = 'off';
            app.ResizeImageButton.Text = 'Resize Image';
            app.EnhancmentTechniquesLabel = uilabel(app.GridLayout2);
            app.EnhancmentTechniquesLabel.Enable = 'off';
            app.EnhancmentTechniquesLabel.Text = 'Enhancment Techniques';
            app.BCETButton = uibutton(app.GridLayout2, 'push');
            app.BCETButton.ButtonPushedFcn = createCallbackFcn(app, @BCETButtonPushed, true);
            app.BCETButton.Enable = 'off';
            app.BCETButton.Text = 'BCET';
            app.OriginalImageButton = uibutton(app.GridLayout2, 'push');
            app.OriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalImageButtonPushed, true);
            app.OriginalImageButton.Enable = 'off';
            app.OriginalImageButton.Text = 'Original Image';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.DetectionLabel = uilabel(app.GridLayout4);
            app.DetectionLabel.Tag = 'DetectionLabel';
            app.DetectionLabel.Text = 'Click Detection Button';
            app.DetectionLabelTitle = uilabel(app.GridLayout4);
            app.DetectionLabelTitle.Text = '';
            app.PAICMonogram = uiimage(app.GridLayout);
            app.PAICMonogram.ImageSource = 'paic-logo.jpg';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Diploma Work Borys Bilkevych 2021';
            app.PAECMonogram_2 = uiimage(app.GridLayout);
            app.PAECMonogram_2.ImageSource = 'good.jpg';
            app.mylamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.Covid19DetectionApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DetectionApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DetectionApplicationUIFigure)
        end
    end
end